classdef pls_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ADASSICKPLSLieveViewUIFigure  matlab.ui.Figure
        SerialPortPanel               matlab.ui.container.Panel
        StatusLampLabel               matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        ConnectButton                 matlab.ui.control.Button
        DisconnectButton              matlab.ui.control.Button
        PortDropDownLabel             matlab.ui.control.Label
        PortList                      matlab.ui.control.DropDown
        ADASSICKPLSLiveViewviaArduinoLabel  matlab.ui.control.Label
        GlareLampLabel                matlab.ui.control.Label
        LampGlare                     matlab.ui.control.Lamp
        WarningFieldLabel             matlab.ui.control.Label
        LampWF                        matlab.ui.control.Lamp
        ProtectionFieldLampLabel      matlab.ui.control.Label
        LampPF                        matlab.ui.control.Lamp
        UIAxes                        matlab.ui.control.UIAxes
        DistanceincmLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        comport;
        baudrate = 115200;
        s1;
        running = false;
        dist = [];
        glare_flag = [];
        wf_v_flag = [];
        pf_v_flag = [];
        angle = linspace(pi,0,180);
        Pax;
        
        GREEN_COLOR = [0 1 0];
        YELLOW_COLOR = [1 1 0];
        RED_COLOR = [1 0 0];
        GRAY_COLOR = [0.4706 0.5059 0.4706];
    end

    methods (Access = private)
    
        function ReadData(app)
            % read and scale data from serial port 
            line = fgets(app.s1);
            line_vector = strsplit(line, ';');
            line_vector = cellfun(@str2double,line_vector);
            switch(line_vector(1))
            case 1
                %dist
                app.dist = line_vector(2:end-1);
            case 2
                %glare_flag
                app.glare_flag = line_vector(2:end-1);
            case 3
                %wf_v_flag
                app.wf_v_flag = line_vector(2:end-1);
            case 4
                %pf_v_flag
                app.pf_v_flag = line_vector(2:end-1);
            end%switch
            
            if ~(isempty(app.dist) || isempty(app.glare_flag) || isempty(app.wf_v_flag) || isempty(app.pf_v_flag))
                % plot
                polarplot(app.Pax, app.angle, app.dist)
                % write Lamps
                if any(app.glare_flag)
                    app.LampGlare.Color = app.YELLOW_COLOR;
                else
                    app.LampGlare.Color = app.GRAY_COLOR;
                end%if
                 if any(app.wf_v_flag)
                    app.LampWF.Color = app.YELLOW_COLOR;
                else
                    app.LampWF.Color = app.GRAY_COLOR;
                end%if
                 if any(app.pf_v_flag)
                    app.LampPF.Color = app.RED_COLOR;
                else
                    app.LampPF.Color = app.GRAY_COLOR;
                end%if
                
                
                app.dist = [];
                app.glare_flag = [];
                app.wf_v_flag = [];
                app.pf_v_flag = [];
            end%if
        end
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %  configure serial connection (COM1 is only a dummy. Will be replaced on connect)
            app.s1 = serial('COM1','BaudRate',app.baudrate,'Terminator','CR','InputBufferSize', 8192);
            app.s1.Terminator = 'CR';
            app.s1.BytesAvailableFcnMode = 'terminator';
            app.s1.BytesAvailableFcn = @(~, ~)app.ReadData;
            % Turn status lamp to disconnected
            app.StatusLamp.Color = app.RED_COLOR;
            
            % looking for available serial ports 
            % (Instrument Control Toolbox required)
            p = instrhwinfo('serial');
            app.PortList.Items = p.AvailableSerialPorts;
            
            
            % Create polar axes and position it in pixels
            delete(app.UIAxes);
            app.Pax = polaraxes(app.ADASSICKPLSLieveViewUIFigure);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [22,36,444,326];          

        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if app.running
                app.running = false;
                disp('Stopping serial connection....')
                fclose(app.s1);
                app.StatusLamp.Color = app.RED_COLOR;
            end%if
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if ~app.running
                app.running = true;
                app.s1.port = app.comport;
                fopen(app.s1);
                app.StatusLamp.Color = app.GREEN_COLOR;
            end%if
            

        end

        % Close request function: ADASSICKPLSLieveViewUIFigure
        function ADASSICKPLSLieveViewUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: PortList
        function PortListValueChanged(app, event)
            app.comport = app.PortList.Value;            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ADASSICKPLSLieveViewUIFigure = uifigure;
            app.ADASSICKPLSLieveViewUIFigure.Name = 'ADAS - SICK PLS Lieve View';
            app.ADASSICKPLSLieveViewUIFigure.CloseRequestFcn = createCallbackFcn(app, @ADASSICKPLSLieveViewUIFigureCloseRequest, true);
            app.SerialPortPanel = uipanel(app.ADASSICKPLSLieveViewUIFigure);
            app.SerialPortPanel.Title = 'Serial Port';
            app.StatusLampLabel = uilabel(app.SerialPortPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.SerialPortPanel);
            app.ConnectButton = uibutton(app.SerialPortPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.SerialPortPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.PortDropDownLabel = uilabel(app.SerialPortPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortList = uidropdown(app.SerialPortPanel);
            app.PortList.Items = {};
            app.PortList.ValueChangedFcn = createCallbackFcn(app, @PortListValueChanged, true);
            app.PortList.Value = {};
            app.ADASSICKPLSLiveViewviaArduinoLabel = uilabel(app.ADASSICKPLSLieveViewUIFigure);
            app.ADASSICKPLSLiveViewviaArduinoLabel.Text = 'ADAS - SICK PLS Live View via Arduino';
            app.GlareLampLabel = uilabel(app.ADASSICKPLSLieveViewUIFigure);
            app.GlareLampLabel.Text = 'Glare';
            app.LampGlare = uilamp(app.ADASSICKPLSLieveViewUIFigure);
            app.WarningFieldLabel = uilabel(app.ADASSICKPLSLieveViewUIFigure);
            app.WarningFieldLabel.Text = 'Warning Field';
            app.LampWF = uilamp(app.ADASSICKPLSLieveViewUIFigure);
            app.ProtectionFieldLampLabel = uilabel(app.ADASSICKPLSLieveViewUIFigure);
            app.ProtectionFieldLampLabel.Text = 'Protection Field';
            app.LampPF = uilamp(app.ADASSICKPLSLieveViewUIFigure);
            app.UIAxes = uiaxes(app.ADASSICKPLSLieveViewUIFigure);
            title(app.UIAxes, {'Dummy Plot (Will be replaced by polarpolot in startup)'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DistanceincmLabel = uilabel(app.ADASSICKPLSLieveViewUIFigure);
            app.DistanceincmLabel.Text = 'Distance in cm';
        end
    end

    methods (Access = public)

        % Construct app
        function app = pls_plot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ADASSICKPLSLieveViewUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ADASSICKPLSLieveViewUIFigure)
        end
    end
end